classdef gooey < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        KpEditFieldLabel               matlab.ui.control.Label
        KpVal                          matlab.ui.control.NumericEditField
        KiEditFieldLabel               matlab.ui.control.Label
        KiVal                          matlab.ui.control.NumericEditField
        KdEditFieldLabel               matlab.ui.control.Label
        KdVal                          matlab.ui.control.NumericEditField
        PullConstantsButton            matlab.ui.control.Button
        TargetAngleEditFieldLabel      matlab.ui.control.Label
        TargetAngleEditField           matlab.ui.control.NumericEditField
        COMEditFieldLabel              matlab.ui.control.Label
        COMEditField                   matlab.ui.control.EditField
        EnableMotorButton              matlab.ui.control.Button
        DisableMotorButton             matlab.ui.control.Button
        CurrentPositionEditFieldLabel  matlab.ui.control.Label
        CurrentPositionEditField       matlab.ui.control.NumericEditField
        PushConstantsButton            matlab.ui.control.Button
        SaveConstantsButton            matlab.ui.control.Button
        GetPositionButton              matlab.ui.control.Button
        GetAngleButton                 matlab.ui.control.Button
        GetButton                      matlab.ui.control.Button
        VEditFieldLabel                matlab.ui.control.Label
        VEditField                     matlab.ui.control.NumericEditField
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Name: Will Dodge
    % E-mail: dodgew@oregonstate.edu
    % GUI for Ardumoto motor shield controlled by Arduino Uno
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
    
    properties (Access = public)
        Property % Description
    end
    
    methods (Access = public)
        
        
        function results = func(app)
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: COMEditField
        function COMEditFieldValueChanged(app, event)
            value = app.COMEditField.Value;
            
            % creates global variable 's' to hold serial port object
            global s
            
            % establishes connection to serial port and assigns it to
            % global variable 's'
            ser=serialport(value,115200,"DataBits",8,"StopBits",1,"Parity","even");
            s=ser;
            pause(3);
        end

        % Button pushed function: EnableMotorButton
        function EnableMotorButtonPushed(app, event)
            % initializes variable 's' in scope of function
            global s
            pause(1);
            
            % creates message to be sent to arduino
             A=[hex2dec('55') hex2dec('AA') 2 double('P') 1];
            
            % displays message to be sent for debugging purposes
            %display(A);
            
            % writes message to serial object 's'
            write(s,A,'uint8');
            
            % reads response from serial object 's' and displays for
            % debugging purposes
            %data=readline(s);
            %display(data); 
           
            % clears stream
            flush(s);
        end

        % Button pushed function: DisableMotorButton
        function DisableMotorButtonPushed(app, event)
            % initializes variable 's' in scope of function
            global s
            pause(1);
            
            % creates message to be sent to arduino
             A=[hex2dec('55') hex2dec('AA') 2 double('P') 0];
            
            % displays message to be sent for debugging purposes
            %display(A);
            
            % writes message to serial object 's'
            write(s,A,'uint8');
            
            % reads response from serial object 's' and displays for
            % debugging purposes
            %data=readline(s);
            %display(data); 
           
            % clears stream
            flush(s);
        end

        % Value changed function: KpVal
        function KpChange(app, event)
            % updates value stored within app for Kp when there is a change 
            value = app.KpVal.Value;
            
        end

        % Value changed function: KiVal
        function KiChange(app, event)
            % updates value stored within app for Ki when there is a change
            value = app.KiVal.Value;
            
        end

        % Value changed function: KdVal
        function KdChange(app, event)
            % updates value stored within app for Kd when there is a change
            value = app.KdVal.Value;
            
        end

        % Button pushed function: PushConstantsButton
        function PushConstantsButtonPushed(app, event)
           % initializes variable 's' in scope of function
            global s
            pause(1);
            
            % converts the values stored in the constant fields to values
            % that can be represented by integers so the arduino can
            % interpret them
            kp=(app.KpVal.Value).*(10^3);
            ki=(app.KiVal.Value).*(10^3);
            kd=(app.KdVal.Value).*(10^3);
            
            % arithmetic for parsing constant values to be sent into two bytes 
            % kp constant
            newKp=mod(kp,256);
            shiftKp=(kp-newKp)./256;
            % ki constant
            newKi=mod(ki,256);
            shiftKi=(ki-newKi)./256;
            % kd constant
            newKd=mod(kd,256);
            shiftKd=(kd-newKd)./256;
            
            % creates message to be sent to arduino
            A=[hex2dec('55') hex2dec('AA') 7 double('C') shiftKp newKp shiftKi newKi shiftKd newKd]; 
            
            % displays message to be sent for debugging purposes
            %display(A);
            
            % writes message to serial object 's'
            write(s,A,'uint8');
            
            % reads response from serial object 's' and displays for
            % debugging purposes
            %data=readline(s);
            %display(data); 
           
            % clears stream
            flush(s);
        end

        % Button pushed function: PullConstantsButton
        function PullConstantsButtonPushed(app, event)
            % initializes variable 's' in scope of function
            global s
            pause(1);
            
            % creates message to be sent to arduino
            A=[hex2dec('55') hex2dec('AA') 1 double('c')];
            
            % displays message to be sent for debugging purposes
            %display(A);
            
            % writes message to serial object 's'
            write(s,A,'uint8');
            
            % reads response from serial object 's'
            data=read(s,10,'uint8'); 
            
            % displays response for debugging purposes
            %display(data); 
                       
            % updates text fields containing constant values with the
            % information received from the arduino
            % arithmetic is for parsing the two bytes of data
            app.KpVal.Value=(data(5).*256)./(1000)+((data(6))./(1000));
            app.KiVal.Value=(data(7).*256)./(1000)+((data(8))./(1000));
            app.KdVal.Value=(data(9).*256)./(1000)+((data(10))./(1000));
            
            % clears stream
            flush(s);
        end

        % Button pushed function: SaveConstantsButton
        function SaveConstantsButtonPushed(app, event)
            % initializes variable 's' in scope of function
            global s
            pause(1);
            
            % creates message to be sent to arduino
            A=[hex2dec('55') hex2dec('AA') 1 double('S')];
            
            % displays message to be sent for debugging purposes
            %display(A);
            
            % writes message to serial object 's'
            write(s,A,'uint8');
            
            % reads response from serial object 's' and displays for
            % debugging purposes
            %data=readline(s);
            %display(data); 
           
            % clears stream
            flush(s);
        end

        % Value changed function: TargetAngleEditField
        function TargetAngleEditFieldValueChanged(app, event)
            % updates value stored within app for target angle when there is a change
            value = app.TargetAngleEditField.Value;
          
            % initializes variable 's' in scope of function
            global s
            pause(1);
            
            % creates message to be sent to arduino
            A=[hex2dec('55') hex2dec('AA') 2 double('T') value]; 
            
            % displays message to be sent for debugging purposes
            %display(A);
            
            % writes message to serial object 's'
            write(s,A,'int8');
            
            % reads response from serial object 's' and displays for
            % debugging purposes
            %data=readline(s);
            %display(data); 
           
            % clears stream
            flush(s);
        end

        % Button pushed function: GetAngleButton
        function GetAngleButtonPushed(app, event)
            % initializes variable 's' in scope of function
            global s
            pause(1);
            
            % creates message to be sent to arduino
            A=[hex2dec('55') hex2dec('AA') 1 double('t')]; 
            
            % displays message to be sent for debugging purposes
            %display(A);
            
            % writes message to serial object 's'
            write(s,A,'uint8');
            
            % reads response from serial object 's'
            data=(read(s,6,'uint8'));
            
            % displays response for debugging purposes
            %display(data); 
            
            % updates value in target angle field
            % arithmetic is for parsing data received
            app.TargetAngleEditField.Value=(data(5).*256)+data(6);
           
            % clears stream
            flush(s);
        end

        % Value changed function: CurrentPositionEditField
        function CurrentPositionEditFieldValueChanged(app, event)
            % updates value stored within app for current position when
            % there is a change
            int(value) = app.CurrentPositionEditField.Value;
        end

        % Button pushed function: GetPositionButton
        function GetPositionButtonPushed(app, event)
            % initializes variable 's' in scope of function
            global s
            pause(1);
            
            % creates message to be sent to arduino
            A=[hex2dec('55') hex2dec('AA') 1 double('s')]; 
            
            % displays message to be sent for debugging purposes
            %display(A);
            
            % writes message to serial object 's'
            write(s,A,'uint8');
            
            % reads response from serial object 's'
            data=(read(s,5,'uint8'));
            
            % displays response for debugging purposes
            %display(data); 
            
            % updates value in current position field
            app.CurrentPositionEditField.Value=data(5);
           
            % clears stream
            flush(s);
        end

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
            % initializes variable 's' in scope of function
            global s
            pause(1);
            
            % creates message to be sent to arduino
            A=[hex2dec('55') hex2dec('AA') 1 double('v')];
            
            % displays message to be sent for debugging purposes
            %display(A);
            
            % writes message to serial object 's'
            write(s,A,'uint8');
            
            data=(read(s,6,'uint8'));
            
            % displays response for debugging purposes
            %display(data); 
            
            % updates value in voltage field
            app.VEditField.Value=(data(6).*(.0049));
           
            % clears stream
            flush(s);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.KpEditFieldLabel = uilabel(app.UIFigure);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpVal = uieditfield(app.UIFigure, 'numeric');
            app.KpVal.ValueChangedFcn = createCallbackFcn(app, @KpChange, true);
            app.KiEditFieldLabel = uilabel(app.UIFigure);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiVal = uieditfield(app.UIFigure, 'numeric');
            app.KiVal.ValueChangedFcn = createCallbackFcn(app, @KiChange, true);
            app.KdEditFieldLabel = uilabel(app.UIFigure);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdVal = uieditfield(app.UIFigure, 'numeric');
            app.KdVal.ValueChangedFcn = createCallbackFcn(app, @KdChange, true);
            app.PullConstantsButton = uibutton(app.UIFigure, 'push');
            app.PullConstantsButton.ButtonPushedFcn = createCallbackFcn(app, @PullConstantsButtonPushed, true);
            app.PullConstantsButton.Text = 'Pull Constants';
            app.TargetAngleEditFieldLabel = uilabel(app.UIFigure);
            app.TargetAngleEditFieldLabel.Text = 'Target Angle';
            app.TargetAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetAngleEditField.ValueChangedFcn = createCallbackFcn(app, @TargetAngleEditFieldValueChanged, true);
            app.COMEditFieldLabel = uilabel(app.UIFigure);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.UIFigure, 'text');
            app.COMEditField.ValueChangedFcn = createCallbackFcn(app, @COMEditFieldValueChanged, true);
            app.COMEditField.Value = 'COM#';
            app.EnableMotorButton = uibutton(app.UIFigure, 'push');
            app.EnableMotorButton.ButtonPushedFcn = createCallbackFcn(app, @EnableMotorButtonPushed, true);
            app.EnableMotorButton.Text = 'Enable Motor';
            app.DisableMotorButton = uibutton(app.UIFigure, 'push');
            app.DisableMotorButton.ButtonPushedFcn = createCallbackFcn(app, @DisableMotorButtonPushed, true);
            app.DisableMotorButton.Text = 'Disable Motor';
            app.CurrentPositionEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentPositionEditFieldLabel.Text = 'Current Position';
            app.CurrentPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentPositionEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentPositionEditFieldValueChanged, true);
            app.PushConstantsButton = uibutton(app.UIFigure, 'push');
            app.PushConstantsButton.ButtonPushedFcn = createCallbackFcn(app, @PushConstantsButtonPushed, true);
            app.PushConstantsButton.Text = 'Push Constants';
            app.SaveConstantsButton = uibutton(app.UIFigure, 'push');
            app.SaveConstantsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConstantsButtonPushed, true);
            app.SaveConstantsButton.Text = 'Save Constants';
            app.GetPositionButton = uibutton(app.UIFigure, 'push');
            app.GetPositionButton.ButtonPushedFcn = createCallbackFcn(app, @GetPositionButtonPushed, true);
            app.GetPositionButton.Text = 'Get Position';
            app.GetAngleButton = uibutton(app.UIFigure, 'push');
            app.GetAngleButton.ButtonPushedFcn = createCallbackFcn(app, @GetAngleButtonPushed, true);
            app.GetAngleButton.Text = 'Get Angle';
            app.GetButton = uibutton(app.UIFigure, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = 'Get';
            app.VEditFieldLabel = uilabel(app.UIFigure);
            app.VEditFieldLabel.Text = 'V';
            app.VEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gooey

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end